classdef Lab_3_logic_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        Results_LeftTab                 matlab.ui.container.Tab
        RadiusEditField_2               matlab.ui.control.NumericEditField
        RadiusEditField_2Label          matlab.ui.control.Label
        CentroidXEditField_2            matlab.ui.control.NumericEditField
        CentroidXEditField_2Label       matlab.ui.control.Label
        CentroidYEditField_2            matlab.ui.control.NumericEditField
        CentroidYEditField_2Label       matlab.ui.control.Label
        ResultsRightTab                 matlab.ui.container.Tab
        RadiusEditField_3               matlab.ui.control.NumericEditField
        RadiusEditField_3Label          matlab.ui.control.Label
        CentroidXEditField_3            matlab.ui.control.NumericEditField
        CentroidXEditField_3Label       matlab.ui.control.Label
        CentroidYEditField_3            matlab.ui.control.NumericEditField
        CentroidYEditField_3Label       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CameraXTab                      matlab.ui.container.Tab
        CameraXCentroidRightEditField   matlab.ui.control.NumericEditField
        CameraXCentroidRightEditFieldLabel  matlab.ui.control.Label
        CameraXCentroidLeftEditField    matlab.ui.control.NumericEditField
        CameraXCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField                  matlab.ui.control.NumericEditField
        RightEditFieldLabel             matlab.ui.control.Label
        LeftEditField                   matlab.ui.control.NumericEditField
        LeftEditFieldLabel              matlab.ui.control.Label
        CameraYTab                      matlab.ui.container.Tab
        CameraYCentroidRightEditField   matlab.ui.control.NumericEditField
        CameraYCentroidRightEditFieldLabel  matlab.ui.control.Label
        CameraYCentroidLeftEditField    matlab.ui.control.NumericEditField
        CameraYCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField_2                matlab.ui.control.NumericEditField
        RightEditField_2Label           matlab.ui.control.Label
        LeftEditField_2                 matlab.ui.control.NumericEditField
        LeftEditField_2Label            matlab.ui.control.Label
        DisparityDepthTab               matlab.ui.container.Tab
        YpositionmmEditField            matlab.ui.control.NumericEditField
        YpositionmmEditFieldLabel       matlab.ui.control.Label
        XpositionmmEditField            matlab.ui.control.NumericEditField
        XpositionmmEditFieldLabel       matlab.ui.control.Label
        depthmmEditField                matlab.ui.control.NumericEditField
        depthmmEditFieldLabel           matlab.ui.control.Label
        disparitymmEditField            matlab.ui.control.NumericEditField
        disparitymmEditFieldLabel       matlab.ui.control.Label
        BallSettingsPanel               matlab.ui.container.Panel
        UpperRadiusBoundEditField       matlab.ui.control.NumericEditField
        UpperRadiusBoundEditFieldLabel  matlab.ui.control.Label
        LowerRadiusBoundEditField       matlab.ui.control.NumericEditField
        LowerRadiusBoundEditFieldLabel  matlab.ui.control.Label
        FrameSettingsPanel              matlab.ui.container.Panel
        PixelSizemmEditField            matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel       matlab.ui.control.Label
        BaselinemmEditField             matlab.ui.control.NumericEditField
        BaselinemmEditFieldLabel        matlab.ui.control.Label
        FocalLengthmmEditField          matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel     matlab.ui.control.Label
        ShiftEditField                  matlab.ui.control.NumericEditField
        ShiftEditFieldLabel             matlab.ui.control.Label
        yNumPixelsEditField             matlab.ui.control.NumericEditField
        yNumPixelsEditFieldLabel        matlab.ui.control.Label
        xNumPixelsEditField             matlab.ui.control.NumericEditField
        xNumPixelsEditFieldLabel        matlab.ui.control.Label
        FindCircleButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindCircleButton
        function FindCircleButtonPushed(app, event)
cols = app.xNumPixelsEditField.Value;
rows = app.yNumPixelsEditField.Value;

w = 100;
h = 100;
colormap gray
            %fold this logic into the lab two app to highlight the centroid
            %adding in a subplot feature is good for visualizing the image
            %data path which will come up later

            %left image
            %Frame_1 = imread("left5.jpg");
            Frame_1 = imread(uigetfile('.png''.jpg'));
            formated_frame_1 = rgb2gray(Frame_1);
            imagesc(formated_frame_1)
            %{
            binary = zeros(rows,cols);
            for x = 1:cols
                for y = 1:rows
                    if (formated_frame_1(y,x) > 150)
                        binary(y,x) = 1;
                    end
                end
            end
            %}
            subplot(1,2,1) 
            
            [center, radius] = imfindcircles(formated_frame_1,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value])
            %hold on
            %TODO: figure out how to plot image to UIAxes or subplot
            viscircles(center, radius, 'Color', 'r')
            scatter(center(1), center(2))
            subplot(1,2,1)
            imshow(formated_frame_1)
            %hold on
            app.CentroidXEditField_2.Value = center(1)
            app.CentroidYEditField_2.Value = center(2)
            app.RadiusEditField_2.Value = radius
            %app.UIAxes = matlab.graphics.eventdata.Annotation

            %right image
            Frame_2 = imread(uigetfile('.png''.jpg'));
            formated_frame_2 = rgb2gray(Frame_2);
            imagesc(formated_frame_2)
            
            %{
            binary2 = zeros(rows,cols);
            for x = 1:cols
                for y = 1:rows
                    if (formated_frame_2(y,x) > 150)
                        binary2(y,x) = 1;
                    end
                end
            end
            %}
            %imshow(formated_frame_2)
            
            
            [center2, radius2] = imfindcircles(formated_frame_2,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value])
            viscircles(center2, radius2, 'Color', 'm')
            scatter(center2(1), center2(2))
            subplot(1,2,2)
            imshow(formated_frame_2)
            app.CentroidXEditField_3.Value = center2(1)
            app.CentroidYEditField_3.Value = center2(2)
            app.RadiusEditField_3.Value = radius2
            
               % cxLeft
               app.LeftEditField.Value = (app.xNumPixelsEditField.Value)/2
               % cxRight
               app.RightEditField.Value = (app.xNumPixelsEditField.Value)/2 
               % xLeft
               app.CameraXCentroidLeftEditField.Value = (app.LeftEditField.Value + app.ShiftEditField.Value)
               % xRight
               app.CameraXCentroidRightEditField.Value = (app.RightEditField.Value - app.ShiftEditField.Value)
               % cyLeft
               app.LeftEditField_2.Value = (app.yNumPixelsEditField.Value)/2
               % cyRight
               app.RightEditField_2.Value = (app.yNumPixelsEditField.Value)/2 
               % yLeft
               app.CameraYCentroidLeftEditField.Value = (app.LeftEditField_2.Value + app.ShiftEditField.Value)
               % yRight
               app.CameraYCentroidRightEditField.Value = (app.RightEditField_2.Value + app.ShiftEditField.Value)
               % disparity in millimeters
               app.disparitymmEditField.Value = (abs((app.CameraXCentroidLeftEditField.Value-app.LeftEditField.Value)-(app.CameraXCentroidRightEditField.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)   
               % depth in millimeters
               app.depthmmEditField.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/app.disparitymmEditField.Value                                    
               % X position in millimeters
               app.XpositionmmEditField.Value = (app.depthmmEditField.Value*(app.CameraXCentroidLeftEditField.Value-app.LeftEditField.Value)*app.PixelSizemmEditField.Value)/app.FocalLengthmmEditField.Value                   
               % Y position in millimeters
               app.YpositionmmEditField.Value = (app.depthmmEditField.Value*(app.CameraYCentroidLeftEditField.Value-app.LeftEditField_2.Value)*app.PixelSizemmEditField.Value)/app.FocalLengthmmEditField.Value     
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FindCircleButton = uibutton(app.UIFigure, 'push');
            app.FindCircleButton.ButtonPushedFcn = createCallbackFcn(app, @FindCircleButtonPushed, true);
            app.FindCircleButton.Text = 'Find Circle';
            app.FrameSettingsPanel = uipanel(app.UIFigure);
            app.FrameSettingsPanel.Title = 'Frame Settings';
            app.xNumPixelsEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.xNumPixelsEditFieldLabel.Text = 'xNumPixels';
            app.xNumPixelsEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.xNumPixelsEditField.Value = 752;
            app.yNumPixelsEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.yNumPixelsEditFieldLabel.Text = 'yNumPixels';
            app.yNumPixelsEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.yNumPixelsEditField.Value = 480;
            app.ShiftEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.ShiftEditField.Value = 10;
            app.FocalLengthmmEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length (mm)';
            app.FocalLengthmmEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.FocalLengthmmEditField.Value = 6;
            app.BaselinemmEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.BaselinemmEditFieldLabel.Text = 'Baseline (mm)';
            app.BaselinemmEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.BaselinemmEditField.Value = 60;
            app.PixelSizemmEditFieldLabel = uilabel(app.FrameSettingsPanel);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size (mm)';
            app.PixelSizemmEditField = uieditfield(app.FrameSettingsPanel, 'numeric');
            app.PixelSizemmEditField.Value = 0.006;
            app.BallSettingsPanel = uipanel(app.UIFigure);
            app.BallSettingsPanel.Title = 'Ball Settings';
            app.LowerRadiusBoundEditFieldLabel = uilabel(app.BallSettingsPanel);
            app.LowerRadiusBoundEditFieldLabel.Text = 'Lower Radius Bound';
            app.LowerRadiusBoundEditField = uieditfield(app.BallSettingsPanel, 'numeric');
            app.LowerRadiusBoundEditField.Value = 30;
            app.UpperRadiusBoundEditFieldLabel = uilabel(app.BallSettingsPanel);
            app.UpperRadiusBoundEditFieldLabel.Text = 'Upper Radius Bound';
            app.UpperRadiusBoundEditField = uieditfield(app.BallSettingsPanel, 'numeric');
            app.UpperRadiusBoundEditField.Value = 80;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraXTab = uitab(app.TabGroup);
            app.CameraXTab.Title = 'Camera X ';
            app.LeftEditFieldLabel = uilabel(app.CameraXTab);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.CameraXTab, 'numeric');
            app.RightEditFieldLabel = uilabel(app.CameraXTab);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraXCentroidLeftEditFieldLabel = uilabel(app.CameraXTab);
            app.CameraXCentroidLeftEditFieldLabel.Text = 'Camera X Centroid Left';
            app.CameraXCentroidLeftEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraXCentroidRightEditFieldLabel = uilabel(app.CameraXTab);
            app.CameraXCentroidRightEditFieldLabel.Text = 'Camera X Centroid Right';
            app.CameraXCentroidRightEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraYTab = uitab(app.TabGroup);
            app.CameraYTab.Title = 'Camera Y';
            app.LeftEditField_2Label = uilabel(app.CameraYTab);
            app.LeftEditField_2Label.Text = 'Left';
            app.LeftEditField_2 = uieditfield(app.CameraYTab, 'numeric');
            app.RightEditField_2Label = uilabel(app.CameraYTab);
            app.RightEditField_2Label.Text = 'Right';
            app.RightEditField_2 = uieditfield(app.CameraYTab, 'numeric');
            app.CameraYCentroidLeftEditFieldLabel = uilabel(app.CameraYTab);
            app.CameraYCentroidLeftEditFieldLabel.Text = 'Camera Y Centroid Left';
            app.CameraYCentroidLeftEditField = uieditfield(app.CameraYTab, 'numeric');
            app.CameraYCentroidRightEditFieldLabel = uilabel(app.CameraYTab);
            app.CameraYCentroidRightEditFieldLabel.Text = 'Camera Y Centroid Right';
            app.CameraYCentroidRightEditField = uieditfield(app.CameraYTab, 'numeric');
            app.DisparityDepthTab = uitab(app.TabGroup);
            app.DisparityDepthTab.Title = 'Disparity/Depth';
            app.disparitymmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.disparitymmEditFieldLabel.Text = 'disparity (mm)';
            app.disparitymmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.depthmmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.depthmmEditFieldLabel.Text = 'depth (mm)';
            app.depthmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.XpositionmmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.XpositionmmEditFieldLabel.Text = 'X position (mm)';
            app.XpositionmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.YpositionmmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.YpositionmmEditFieldLabel.Text = 'Y position (mm)';
            app.YpositionmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Results_LeftTab = uitab(app.TabGroup2);
            app.Results_LeftTab.Title = 'Results_Left';
            app.CentroidYEditField_2Label = uilabel(app.Results_LeftTab);
            app.CentroidYEditField_2Label.Text = 'Centroid Y';
            app.CentroidYEditField_2 = uieditfield(app.Results_LeftTab, 'numeric');
            app.CentroidXEditField_2Label = uilabel(app.Results_LeftTab);
            app.CentroidXEditField_2Label.Text = 'Centroid X';
            app.CentroidXEditField_2 = uieditfield(app.Results_LeftTab, 'numeric');
            app.RadiusEditField_2Label = uilabel(app.Results_LeftTab);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.RadiusEditField_2 = uieditfield(app.Results_LeftTab, 'numeric');
            app.ResultsRightTab = uitab(app.TabGroup2);
            app.ResultsRightTab.Title = 'Results Right';
            app.CentroidYEditField_3Label = uilabel(app.ResultsRightTab);
            app.CentroidYEditField_3Label.Text = 'Centroid Y';
            app.CentroidYEditField_3 = uieditfield(app.ResultsRightTab, 'numeric');
            app.CentroidXEditField_3Label = uilabel(app.ResultsRightTab);
            app.CentroidXEditField_3Label.Text = 'Centroid X';
            app.CentroidXEditField_3 = uieditfield(app.ResultsRightTab, 'numeric');
            app.RadiusEditField_3Label = uilabel(app.ResultsRightTab);
            app.RadiusEditField_3Label.Text = 'Radius';
            app.RadiusEditField_3 = uieditfield(app.ResultsRightTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_3_logic_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end